classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Z_valueLabel              matlab.ui.control.Label
        Y_valueLabel              matlab.ui.control.Label
        X_valueLabel              matlab.ui.control.Label
        PositionZLabel            matlab.ui.control.Label
        PositionYLabel            matlab.ui.control.Label
        PositionXLabel            matlab.ui.control.Label
        ArduinoConnectionLabel    matlab.ui.control.Label
        SerialPortEditField       matlab.ui.control.EditField
        SerialPortEditFieldLabel  matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ForwardKinematicsLabel    matlab.ui.control.Label
        q3_valueLabel             matlab.ui.control.Label
        q2_valueLabel             matlab.ui.control.Label
        q1_valueLabel             matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        ManualModeLabel           matlab.ui.control.Label
        Q3Knob                    matlab.ui.control.Knob
        Q3KnobLabel               matlab.ui.control.Label
        Q2Knob                    matlab.ui.control.Knob
        Q2KnobLabel               matlab.ui.control.Label
        Q1Knob                    matlab.ui.control.Knob
        Q1KnobLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device % Property to connect the serial port
        theta1_dyn = 0; 
        theta2_dyn = 500;
        theta3_dyn = 0;
        COM
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Start the serial communication 
            disp("Starting Serial Communication") 
            app.device = serialport(app.COM, 9600); 

            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Starting Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changing function: Q1Knob
        function Q1KnobValueChanging(app, event)
            theta1_deg = event.Value;
            app.q1_valueLabel.Text = strcat(int2str(theta1_deg), "°");
            app.theta1_dyn = theta1_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            writeline(app.device, theta_array) 
            
            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changing function: Q2Knob
        function Q2KnobValueChanging(app, event)
            theta2_deg = event.Value;
            app.q2_valueLabel.Text = strcat(int2str(theta2_deg), "°");
            app.theta2_dyn = theta2_deg * 1023 / 300;
            
            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            writeline(app.device, theta_array) 
            
            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changing function: Q3Knob
        function Q3KnobValueChanging(app, event)
            theta3_deg = event.Value;
            app.q3_valueLabel.Text = strcat(int2str(theta3_deg), "°");
            app.theta3_dyn = theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
            writeline(app.device, theta_array) 
            
            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changed function: Q1Knob
        function Q1KnobValueChanged(app, event)
            theta1_deg = app.Q1Knob.Value;
            app.q1_valueLabel.Text = strcat(int2str(theta1_deg), "°");
            app.theta1_dyn = theta1_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            writeline(app.device, theta_array) 
            
            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changed function: Q2Knob
        function Q2KnobValueChanged(app, event)
            theta2_deg = app.Q2Knob.Value;
            app.q2_valueLabel.Text = strcat(int2str(theta2_deg), "°");
            app.theta2_dyn = theta2_deg * 1023 / 300;
            
            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            writeline(app.device, theta_array) 
            
            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changed function: Q3Knob
        function Q3KnobValueChanged(app, event)
            theta3_deg = app.Q3Knob.Value;
            app.q3_valueLabel.Text = strcat(int2str(theta3_deg), "°");
            app.theta3_dyn = theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            writeline(app.device, theta_array) 
            
            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 
        end

        % Value changed function: SerialPortEditField
        function SerialPortEditFieldValueChanged(app, event)
            app.COM = app.SerialPortEditField.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Q1KnobLabel = uilabel(app.UIFigure);
            app.Q1KnobLabel.Text = 'Q1';
            app.Q1Knob = uiknob(app.UIFigure, 'continuous');
            app.Q1Knob.Limits = [0 300];
            app.Q1Knob.ValueChangedFcn = createCallbackFcn(app, @Q1KnobValueChanged, true);
            app.Q1Knob.ValueChangingFcn = createCallbackFcn(app, @Q1KnobValueChanging, true);
            app.Q2KnobLabel = uilabel(app.UIFigure);
            app.Q2KnobLabel.Text = 'Q2';
            app.Q2Knob = uiknob(app.UIFigure, 'continuous');
            app.Q2Knob.Limits = [0 300];
            app.Q2Knob.ValueChangedFcn = createCallbackFcn(app, @Q2KnobValueChanged, true);
            app.Q2Knob.ValueChangingFcn = createCallbackFcn(app, @Q2KnobValueChanging, true);
            app.Q3KnobLabel = uilabel(app.UIFigure);
            app.Q3KnobLabel.Text = 'Q3';
            app.Q3Knob = uiknob(app.UIFigure, 'continuous');
            app.Q3Knob.Limits = [0 300];
            app.Q3Knob.ValueChangedFcn = createCallbackFcn(app, @Q3KnobValueChanged, true);
            app.Q3Knob.ValueChangingFcn = createCallbackFcn(app, @Q3KnobValueChanging, true);
            app.ManualModeLabel = uilabel(app.UIFigure);
            app.ManualModeLabel.Text = 'Manual Mode';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.q1_valueLabel = uilabel(app.UIFigure);
            app.q1_valueLabel.Text = 'q1_value';
            app.q2_valueLabel = uilabel(app.UIFigure);
            app.q2_valueLabel.Text = 'q2_value';
            app.q3_valueLabel = uilabel(app.UIFigure);
            app.q3_valueLabel.Text = 'q3_value';
            app.ForwardKinematicsLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsLabel.Text = {'Forward Kinematics'; ''};
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.ValueChangedFcn = createCallbackFcn(app, @SerialPortEditFieldValueChanged, true);
            app.ArduinoConnectionLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionLabel.Text = 'Arduino Connection';
            app.PositionXLabel = uilabel(app.UIFigure);
            app.PositionXLabel.Text = 'Position X:';
            app.PositionYLabel = uilabel(app.UIFigure);
            app.PositionYLabel.Text = 'Position Y:';
            app.PositionZLabel = uilabel(app.UIFigure);
            app.PositionZLabel.Text = 'Position Z:';
            app.X_valueLabel = uilabel(app.UIFigure);
            app.X_valueLabel.Text = 'X_value';
            app.Y_valueLabel = uilabel(app.UIFigure);
            app.Y_valueLabel.Text = 'Y_value';
            app.Z_valueLabel = uilabel(app.UIFigure);
            app.Z_valueLabel.Text = 'Z_value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end